classdef UI_EyeTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StoricoTable           matlab.ui.control.Table
        AnalizzaTrendButton    matlab.ui.control.Button
        PazienteDropDown       matlab.ui.control.DropDown
        PazienteDropDownLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Database_finale % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Carico il file dal disco dentro la proprietà dell'app
dati_salvati = load('Database_eyetracking.mat');
app.Database_finale = dati_salvati.Database_finale;

% 2. Estraggo gli ID dei pazienti e li inserisco nel DropDown
pazienti_univoci = unique(app.Database_finale.ID_Paziente);
app.PazienteDropDown.Items = string(pazienti_univoci);
        end

        % Button pushed function: AnalizzaTrendButton
        function AnalizzaTrendButtonPushed(app, event)

    % 1. Legge il valore selezionato nel DropDown come testo
    valore_selezionato = string(app.PazienteDropDown.Value);

    % 2. Filtra il database per il paziente scelto
    Filtro = string(app.Database_finale.ID_Paziente) == valore_selezionato;
    Dati_Paziente = app.Database_finale(Filtro, :);

    % 3. Avviso se non ci sono dati
    if isempty(Dati_Paziente)
        uialert(app.UIFigure, 'Nessun dato trovato per il paziente selezionato.', 'Attenzione');
        return;
    end

    % --- PULIZIA GRAFICO PRECEDENTE ---
    cla(app.UIAxes);

    % 4. GRAFICO CON CURVA DI TENDENZA CLINICA (Polinomio di 2° grado)
    numero_acquisizioni = (1:height(Dati_Paziente))'; 
    velocita = Dati_Paziente.Velocita_Picco_deg_s;    

    % Disegna i dati grezzi (puntini blu in sottofondo)
    plot(app.UIAxes, numero_acquisizioni, velocita, 'o', ...
         'MarkerSize', 4, 'MarkerFaceColor', '#0072BD', 'MarkerEdgeColor', 'none');
    hold(app.UIAxes, 'on');

    % Escludiamo i battiti di ciglia (NaN) per il calcolo del trend
    filtro_validi = ~isnan(velocita);
    x_validi = numero_acquisizioni(filtro_validi);
    y_validi = velocita(filtro_validi);

    % Se ci sono abbastanza dati validi, calcoliamo una curva di tendenza
    if length(x_validi) > 5
        coefficienti = polyfit(x_validi, y_validi, 2); 
        trend_curva = polyval(coefficienti, numero_acquisizioni);
        
        % Disegna la curva di tendenza arancione
        plot(app.UIAxes, numero_acquisizioni, trend_curva, '-', ...
             'LineWidth', 3.5, 'Color', '#D95319');
    end
    hold(app.UIAxes, 'off');

    % Titolo dinamico sicuro con sprintf (evita qualsiasi errore di sintassi)
    gruppo_corrente = string(Dati_Paziente.Gruppo_sano_patologico(1));
    testo_titolo = sprintf('Trend Velocità - Paziente %s (%s)', valore_selezionato, gruppo_corrente);
    title(app.UIAxes, testo_titolo);
    
    xlabel(app.UIAxes, "Numero Acquisizione (Ordine Cronologico)");
    ylabel(app.UIAxes, "Velocità di Picco (deg/s)");
    grid(app.UIAxes, 'on');

    % Imposta limiti dinamici
    app.UIAxes.YLim = [0, 2000]; 
    app.UIAxes.XLim = [0.5, height(Dati_Paziente) + 0.5];

    % 5. TABELLA RIPRISTINATA E CORRETTA
    app.StoricoTable.Data = Dati_Paziente;
    app.StoricoTable.ColumnName = Dati_Paziente.Properties.VariableNames;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'TrendAxes')
            xlabel(app.UIAxes, 'ID_Acquisizione')
            ylabel(app.UIAxes, 'Velocità di Picco (deg/s)')
            zlabel(app.UIAxes, 'Z')
            app.PazienteDropDownLabel = uilabel(app.UIFigure);
            app.PazienteDropDownLabel.Text = 'PazienteDropDown';
            app.PazienteDropDown = uidropdown(app.UIFigure);
            app.AnalizzaTrendButton = uibutton(app.UIFigure, 'push');
            app.AnalizzaTrendButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizzaTrendButtonPushed, true);
            app.AnalizzaTrendButton.Text = 'Analizza Trend';
            app.StoricoTable = uitable(app.UIFigure);
            app.StoricoTable.ColumnName = {'ID_Paziente'; 'Eta'; 'Sesso'; 'Gruppo_sano_patologico'; 'Scolarita'; 'ID_Diagnosi'; 'Patologia'; 'Punteggio_Clinico'; 'ID_Acquisizione'; 'Data_Test'; 'Stato_Farmaco(ON/OFF)'; 'Task'; 'Freq_Campionamento_Hz'; 'ID_Metrica'; 'Stimolo'; 'Categoria'; 'Latenza_ms'; 'Durata_Fissazione_ms'; 'Diametro_Pupillare_Medio_mm'; 'Ampiezza_deg'; 'Velocita_Picco_deg_s'; 'Guadagno_Pursuit'; 'Error_Rate_Perc'};
            app.StoricoTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_EyeTracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end